classdef SolarSpecGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ResetButton                     matlab.ui.control.Button
        ExportFiguresButton             matlab.ui.control.Button
        LogLogFigureButton              matlab.ui.control.Button
        SaveVarstoWorkspaceButton       matlab.ui.control.Button
        LoadLogData                     matlab.ui.control.Button
        LoadCreateError                 matlab.ui.control.Label
        GridLayout9                     matlab.ui.container.GridLayout
        LogDataButton                   matlab.ui.control.Button
        LinearDataButton                matlab.ui.control.Button
        LoadTDMSdataButton              matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        NextButton                      matlab.ui.control.Button
        PrevButton                      matlab.ui.control.Button
        KeepSpecPlotYaxisfixedCheckBox  matlab.ui.control.CheckBox
        GridLayout5                     matlab.ui.container.GridLayout
        ButtonGroup                     matlab.ui.container.ButtonGroup
        LinearYscaleButton              matlab.ui.control.RadioButton
        LogYScaleButton                 matlab.ui.control.RadioButton
        KeepKinPlotaxisfixedCheckBox    matlab.ui.control.CheckBox
        KinPlot                         matlab.ui.control.UIAxes
        SpecPlot                        matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        BackgroundLvlDisplay            matlab.ui.control.Label
        LogarithmicdatawillbeexportedtoCSVLabel  matlab.ui.control.Label
        ExportSpectraDataButton         matlab.ui.control.Button
        ExportLogDataCSVButton          matlab.ui.control.Button
        TimeFields                      matlab.ui.control.EditField
        CentertimesforspectraSeperatewithcolonLabel  matlab.ui.control.Label
        KineticLevelDisplay             matlab.ui.control.Label
        SpectraTimeWidthSlider          matlab.ui.control.Slider
        SpectraTimeWidthLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        LogApp % Description
    end
    
    methods (Access = private)

        function RGPlot(app)
            global TimeRG KinInd DataRG SpecDataPlot wavelengths DataPlot SpecYrange changeAxes BackgroundLvl
            global tamOn positions KinDataPlot FixedYrange plot_wavelengths
            if nargin == 1
                % Global variables used across different functions

                % Generate Data to plot

                %% Call BuildSpecData First

                %RGBuildSpecData(app);

                %Sort the wavelgths headers
                plot_wavelengths = sort(str2double(wavelengths));

                if changeAxes
                    SpecYrange = [min(min(SpecDataPlot)) max(max(SpecDataPlot))];
                end


                KinData = DataRG(:,2:end);
                KinDataPlot = KinData(:,KinInd);

                % Clear plots to ensure no overlap

                cla(app.SpecPlot, 'reset');
                %cla(app.KinPlot, 'reset');

                % Plot bars first
                RGcolor = hsv(10);
                %xPoints = [];
                %if isempty(SpecDataPlot) == 0
                %for i = 1:1:size(SpecDataPlot,2)
                %                         hold(app.KinPlot, 'on');
                %                         RGarea = ShadeArea{i,1};
                %                         KinShade(i) = area(app.KinPlot, RGarea, [1 1]);
                %
                %
                %
                %                         xPoints(i,:) = KinShade(i).XData;

                %plot(app.KinPlot,xPoints(i,1),0,'ko--','MarkerSize', 12);
                %plot(app.KinPlot,xPoints(i,2),0,'ko--', 'MarkerSize',12);
                %                         KinShade(i).FaceColor = RGcolor(i,:);
                %
                %                         if i==1
                %                             KinShade(i).BaseValue = -1;
                %                         end
                %end
                %disp(xPoints)
                %end

                % Plot the data on axes figure
                delete(DataPlot)
                hold(app.KinPlot, 'on');
                DataPlot= plot(app.KinPlot, table2array(TimeRG), table2array(KinDataPlot), 'b');
                hold(app.KinPlot, 'off');

                % Check if Y-axis is to be kept constant
                if app.KeepKinPlotaxisfixedCheckBox.Value == 0
                    axis(app.KinPlot,[1E-7 max(table2array(TimeRG)) min(table2array(KinDataPlot)) max(table2array(KinDataPlot))]);
                else
                    axis(app.KinPlot,[1E-7 max(table2array(TimeRG)) FixedYrange]);
                end


                % Keep X scale Logarithmic
                app.KinPlot.XScale = 'log';

                % Plot reference Y = 0 line if Y scale is linear.
                if strcmp(app.KinPlot.YScale,'linear') == 1
                    KinRef = refline(app.KinPlot, 0, 0);
                    KinRef.Color = [0 0 0];
                end

                % Plot Spectral data
                if isempty(SpecDataPlot) == 0
                    for i = 1:1:size(SpecDataPlot,2)
                        plot(app.SpecPlot, plot_wavelengths, SpecDataPlot(:,i), 'Color', RGcolor(i,:));
                        hold(app.SpecPlot, 'on');
                    end
                    SpecRef = refline(app.SpecPlot,0,0);
                    SpecRef.Color = [0 0 0];
                    hold(app.SpecPlot, 'off');

                    if changeAxes
                        if tamOn == 0
                            axis(app.SpecPlot, [plot_wavelengths(1) max(plot_wavelengths) min(min(SpecDataPlot)) max(max(SpecDataPlot))]);
                            SpecYrange = [min(min(SpecDataPlot)) max(max(SpecDataPlot))];
                        else
                            SpecYrange = [min(min(SpecDataPlot)) max(max(SpecDataPlot))];
                        end
                    else
                        if tamOn == 0
                            axis(app.SpecPlot, [plot_wavelengths(1) max(plot_wavelengths) SpecYrange]);
                            %axis(app.SpecPlot, [plot_wavelengths(1) max(plot_wavelengths) min(min(SpecDataPlot)) max(max(SpecDataPlot))]);
                        else
                            ...
                        end
                    end
                end
                if tamOn == 0
                    app.KineticLevelDisplay.Text = "Displaying kinetic at " + plot_wavelengths(KinInd) + " nm";
                    app.BackgroundLvlDisplay.Text = "Background level was " + table2array(BackgroundLvl(1,KinInd)) + " V"; %RG
                else
                    app.KineticLevelDisplay.Text = "Displaying " + positions(KinInd);
                    app.BackgroundLvlDisplay.Text = "Background level was " + table2array(BackgroundLvl(1,KinInd)) + " V";
                end
            else
                ...
            end
        end

        function RGBuildSpecData(app, ~, ~)
            %% Change all to linear data
            % Global Variables with use throughout the app
            global DataRG TimeRG NumberOfTraces linData TestCenter
            global SpecDataPlot;
            global ShadeArea;
            global xPoints;
            global xPointsROI KinShade
            RGcolor = hsv(10);
            %% If called without callback execute default
            if nargin == 1
                SpecDataPlot = [];
                ShadeArea = [];

                xPoints = [];


                TimeRG = DataRG(:,1);

                TimeRGSpec = linData(:,1);
                %NumberOfTraces = size(DataRG, 2) - 1;
                NumberOfTraces = size(linData, 2) - 1;

                SpecCenterTimes = str2double(split(app.TimeFields.Value,';'));

                SpecWidth = app.SpectraTimeWidthSlider.Value/100;

                for Spec = 1:1:length(SpecCenterTimes)
                    CenterTime = SpecCenterTimes(Spec,1);
                    StartTime = CenterTime *(1 - SpecWidth/2);
                    EndTime = CenterTime * (1 + SpecWidth/2);


                    StartInd = find(StartTime > table2array(TimeRGSpec),1,'last') + 1;
                    EndInd = find(EndTime < table2array(TimeRGSpec),1,'first') - 1;

                    SpecDataPlot(:,Spec) = mean(table2array(linData(StartInd:EndInd,2:end)),1);

                    ShadeArea{Spec,1} = [StartTime EndTime];
                end
                %% Start of Plotting interactible area

                RGcolor = hsv(10);
                %% Global Variable to hold the plotted areas
                KinShade = [];
                if isempty(SpecDataPlot) == 0
                    %% On first draw, reset Graph
                    cla(app.KinPlot, 'reset');
                    %% Plot bars and store the data of the ends
                    for i = 1:1:size(SpecDataPlot,2)
                        hold(app.KinPlot, 'on');
                        RGarea = ShadeArea{i,1};
                        disp(RGarea);
                        KinShade{i} = area(app.KinPlot, RGarea, [1 1]);
                        %% Store Upper and Lower X coordinates
                        xPoints(i,:) = KinShade{i}.XData;
                        KinShade{i}.FaceColor = RGcolor(i,:);

                        if strcmp(app.KinPlot.YScale, 'log')
                            KinShade{i}.BaseValue = 1e-100;
                        else
                            KinShade{i}.BaseValue = -1;
                        end
                    end
                end
                %% Cell Array to keep track of every ROI position
                xPointsROI = [];
                for i = 1:1:size(xPoints)
                    %% Implement drawpoint for either side of the area
                    if ~isnan(xPoints(i,1)) && ~isnan(xPoints(i,2))
                        X1 = drawpoint(app.KinPlot, "Position", [xPoints(i,1) 0.0001]);
                        X2 = drawpoint(app.KinPlot, "Position", [xPoints(i,2) 0.0001]);
                        %% Add callbacks for when point if moved
                        addlistener(X1, "MovingROI", @(src,event)RGBuildSpecData(app,src,event));
                        addlistener(X2, "MovingROI", @(src,event)RGBuildSpecData(app,src,event));
                        %% Store points in vector
                        xPointsROI{i,1} = X1;
                        xPointsROI{i,2} = X2;
                    end
                    %disp(xPointsROI{i});
                end
                %% Plot Graph with new areas selected
                %% TODO
                %% ==================================== %%
                %% Add logic such that on Plot, areas do not change
                %% ==================================== %%

                RGPlot(app)
            else
                TestCenter = [];
                TestCenter = xPoints(:,1) + xPoints(:,2);
                TestCenter = TestCenter./2;


                TimeRGSpec = linData(:,1);

                %disp(num2str(TestCenter, '%.2E'))
                TimeFieldsNew = "";
                for i = 1:1:size(TestCenter)
                    TimeFieldsNew = TimeFieldsNew + num2str(TestCenter(i), '%.2E') + "; ";
                    StartTime = xPoints(i,1);
                    EndTime = xPoints(i,2);


                    StartInd = find(StartTime > table2array(TimeRGSpec),1,'last') + 1;
                    EndInd = find(EndTime < table2array(TimeRGSpec),1,'first') - 1;

                    SpecDataPlot(:,i) = mean(table2array(linData(StartInd:EndInd,2:end)),1);

                    ShadeArea{i,1} = [StartTime EndTime];
                end
                app.TimeFields.Value=TimeFieldsNew;

                for i = 1:1:size(xPointsROI)
                    ShadeArea{i,1}=[xPointsROI{i,1}.Position(1),xPointsROI{i,2}.Position(1)];
                end
                for i = 1:1:size(SpecDataPlot,2)
                    hold(app.KinPlot, 'on');
                    RGarea = ShadeArea{i,1};
                    delete(KinShade{i});
                    KinShade{i} = area(app.KinPlot, RGarea, [1 1]);
                    xPoints(i,:) = KinShade{i}.XData;
                    KinShade{i}.FaceColor = RGcolor(i,:);
                    if i==1
                        disp(app.KinPlot.YScale)
                        if strcmp(app.KinPlot.YScale, 'log')
                            KinShade{i}.BaseValue = 1e-100;
                        else
                            KinShade{i}.BaseValue = -1;
                        end
                    end
                end
                RGPlot(app)
                %disp(RGarea)

            end
        end


        function plotLogData(app)
            global NumberOfTraces T KinInd plot_wavelengths LogPlot
            KinData = T(:,2:end);
            KinDataPlot = KinData(:,KinInd);
            TimeRG = T(:,1);
            NumberOfTraces = size(T, 2) - 1;
            KinYrange = app.KinPlot.YLim;

            delete(LogPlot)
            LogPlot = plot(app.KinPlot, table2array(TimeRG), table2array(KinDataPlot), 'b');

            app.KinPlot.XScale = "log";
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.LogYScaleButton
                app.KinPlot.YScale = 'log';
            else
                app.KinPlot.YScale = 'linear';
            end
            app.KineticLevelDisplay.Text = "Displaying kinetic at " + plot_wavelengths(KinInd) + " nm";
            
            if app.KeepKinPlotaxisfixedCheckBox.Value == 0
                axis(app.KinPlot,[1E-7 max(table2array(TimeRG)) min(table2array(KinDataPlot)) max(table2array(KinDataPlot))]);
            else
                axis(app.KinPlot, [1E-7 max(table2array(TimeRG)) KinYrange]);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Determine where your m-file's folder is.
            folder = fileparts(which(mfilename));
            % Add that folder plus all subfolders to the path.
            addpath(genpath(folder));

            app.GridLayout3.Visible = false;
            app.GridLayout9.Visible = false;
            app.CenterPanel.Visible = false;
            app.RightPanel.Visible = false;
            app.SaveVarstoWorkspaceButton.Visible = false;
            app.LogLogFigureButton.Visible = false;
            app.ExportFiguresButton.Visible = false;
            app.ResetButton.Visible = false;
        end

        % Button pushed function: LoadTDMSdataButton
        function loadDataButton(app, event)
            % Global Variables used across different functions
            global DataRG wavelengths OpenedFolder logData linData IsLogDataOnly changeAxes BackgroundLvl
            global KinInd; KinInd = 1; IsLogDataOnly=false; changeAxes = true;
            global tamOn positions Wavelength LinData LogData;

            app.LoadCreateError.Text = "Loading data...";
            try
                SpectraBuilderloadTDMSfolder
                %% Check if matlab is installed
                matlab.addons.isAddonEnabled('Image Processing Toolbox')
            catch ERROR
                switch ERROR.identifier
                    case 'MATLAB:UndefinedFunction'
                        app.LoadCreateError.Text = 'Function: "SpectraBuilderloadTDMSfolder" is not added to path';
                        return;
                    case 'matlab_addons:enableDisableManagement:invalidIdentifier'
                        app.LoadCreateError.Text = "Please install 'Image Processing Toolbox' to continue";
                        return;
                    otherwise
                        app.LoadCreateError.Text = 'Unknown error';
                        return;
                end
            end

            app.GridLayout3.Visible = true;
            app.GridLayout9.Visible = true;
            app.CenterPanel.Visible = true;
            app.RightPanel.Visible = true;
            app.SpecPlot.Visible = true; %RG
            app.SpectraTimeWidthLabel.Visible = true;
            app.LinearDataButton.Visible = true;
            app.LogDataButton.Visible=true;
            app.SpectraTimeWidthSlider.Visible = true;
            app.ExportLogDataCSVButton.Visible = true;
            app.ExportSpectraDataButton.Visible = true;
            app.TimeFields.Visible = true;
            app.CentertimesforspectraSeperatewithcolonLabel.Visible = true;
            app.KeepSpecPlotYaxisfixedCheckBox.Visible = true;
            app.BackgroundLvlDisplay.Visible = true;
            app.SaveVarstoWorkspaceButton.Visible = true;
            app.LogLogFigureButton.Visible = true;
            app.ExportFiguresButton.Visible = true;
            app.ResetButton.Visible = true;
            
            OpenedFolder = FolderPath;
            %             logData = LogData;
            %             linData = LinData;

            % Sort the table columns by wavelength

            if tamOn == 0
                %% Look at the data tables
                wavelengths = LinData.Properties.VariableNames(2:end);
                wavelengths_order = cellfun(@(x)sscanf(x, 'x%d'), wavelengths);
                [~, sortedWavelengthsOrder] = sort(wavelengths_order);
                BackgroundLvl = BackgroundLvl(:,sortedWavelengthsOrder);
                sortedWavelengthsOrder = sortedWavelengthsOrder + 1; %Change sorting array to fit Time in first position
                sortedWavelengthsOrder = [1 sortedWavelengthsOrder];
                logData = LogData(:, sortedWavelengthsOrder);
                linData = LinData(:, sortedWavelengthsOrder);

                DataRG = logData;


                for i = 1:1:length(wavelengths)
                    wavelengths(i) = erase(wavelengths(i), 'x');
                end
                [~, name] = fileparts(FolderPath);
                app.LoadCreateError.Text = "Opened folder: " + name;
                RGBuildSpecData(app);
            else
                %% Look at the data tables
                wavelengths = {Wavelength};
                %wavelengths = BackgroundLvl.Properties.VariableNames(1:end);
                %wavelengths_order = cellfun(@(x)sscanf(x, 'x%d'), wavelengths);
                %[~, sortedWavelengthsOrder] = sort(wavelengths_order);

                positions = LinData.Properties.VariableNames(2:end);
                positions_order = cellfun(@(x)sscanf(x, 'x%d'), positions);
                [~, sortedPositionsOrder] = sort(positions_order);
                BackgroundLvl = BackgroundLvl(:,sortedPositionsOrder);
                sortedPositionsOrder = sortedPositionsOrder + 1;
                sortedPositionsOrder = [1 sortedPositionsOrder];
                logData = LogData(:, sortedPositionsOrder);
                linData = LinData(:, sortedPositionsOrder);

                DataRG = logData;


                for i = 1:1:length(positions)
                    positions(i) = erase(positions(i), 'x');
                end

                [~, name] = fileparts(FolderPath);
                app.LoadCreateError.Text = "Opened folder: " + name;
                RGBuildSpecData(app);
            end
        end

        % Button pushed function: PrevButton
        function PrevButtonPressed(app, event)
            global KinInd IsLogDataOnly
            KinInd = KinInd - 1;
            if KinInd < 1
                KinInd = 1;
            end
            if ~IsLogDataOnly
                RGPlot(app);
            else
                plotLogData(app);
            end

        end

        % Button pushed function: NextButton
        function NextButtonPressed(app, event)
            global KinInd NumberOfTraces IsLogDataOnly
            KinInd = KinInd + 1;
            if KinInd > NumberOfTraces
                KinInd = NumberOfTraces;
            end
            if ~IsLogDataOnly
                RGPlot(app);
            else
                plotLogData(app);
            end
        end

        % Value changed function: SpectraTimeWidthSlider
        function TimeWidthChangedFcn(app, event)
            value = app.SpectraTimeWidthSlider.Value;
            app.SpectraTimeWidthSlider.Value = 5*round(value/5);
            clear xPoints
            RGBuildSpecData(app)
        end

        % Value changing function: SpectraTimeWidthSlider
        function TimeWidthChangingFcn(app, event)
            changingValue = event.Value;
            if changingValue < 5
                changingValue = 5;
            end
            app.SpectraTimeWidthSlider.Value = 5*round(changingValue/5);
        end

        % Value changed function: KeepSpecPlotYaxisfixedCheckBox
        function KeepSpecPlotYaxisfixed(app, event)
            global changeAxes
            changeAxes = ~ changeAxes;
            RGPlot(app)
        end

        % Value changed function: KeepKinPlotaxisfixedCheckBox
        function KeepKinPlotaxisfixed(app, event)
            global IsLogDataOnly FixedYrange KinDataPlot TimeRG %changeAxes
            %changeAxes = ~changeAxes;
            
            if app.KeepKinPlotaxisfixedCheckBox.Value==1
                switch app.KinPlot.YScale
                    case 'log'
                        KinDataArray = table2array(KinDataPlot);
                        TimeArray = table2array(TimeRG);
                        KinDataPlotPos = (KinDataArray(TimeArray>0));
                        KinDataPlotPos = (KinDataPlotPos(KinDataPlotPos>0));
                        FixedYrange = [min(KinDataPlotPos) max(KinDataArray)];
                    case 'linear'
                        FixedYrange = app.KinPlot.YLim;
                end
            end
            
            if ~IsLogDataOnly
                RGPlot(app)
            else
                plotLogData(app)
            end
        end

        % Selection changed function: ButtonGroup
        function selectLogOrLin(app, event)
            global IsLogDataOnly KinDataPlot FixedYrange
            selectedButton = app.ButtonGroup.SelectedObject;
            
            if selectedButton == app.LogYScaleButton
                app.KinPlot.YScale = 'log';
            else
                app.KinPlot.YScale = 'linear';
            end

            if app.KeepKinPlotaxisfixedCheckBox==1
                switch app.KinPlot.YScale
                    case 'log'
                        KinDataArray = table2array(KinDataPlot);
                        KinDataPlotPos = (KinDataArray(TimeArray>0));
                        KinDataPlotPos = (KinDataPlotPos(KinDataPlotPos>0));
                        FixedYrange = [min(KinDataPlotPos) max(KinDataArray)];
                    case 'linear'
                        FixedYrange = app.KinPlot.YLim;
                end
            end
            
            if ~IsLogDataOnly
                RGBuildSpecData(app,1,1)
            end
        end

        % Button pushed function: LinearDataButton
        function setLinData(app, event)
            global DataRG linData
            DataRG = linData;
            RGBuildSpecData(app);
        end

        % Button pushed function: LogDataButton
        function setLogData(app, event)
            global DataRG logData
            DataRG = logData;
            RGBuildSpecData(app);
        end

        % Button pushed function: ExportLogDataCSVButton
        function ExportLogDataCSVButtonPushed(app, event)
            app.LogarithmicdatawillbeexportedtoCSVLabel.Visible = true;
            global logData OpenedFolder

            [~, name] = fileparts(OpenedFolder);
            name = OpenedFolder + "\" + name + "_KIN.csv";
            %plot_wavelengths = sort(str2double(wavelengths));
            writetable(logData, name, "Delimiter", ',', "QuoteStrings",true);
            app.LogarithmicdatawillbeexportedtoCSVLabel.Text = 'Data has been exported';
        end

        % Button pushed function: ExportSpectraDataButton
        function ExportSpectraDataButtonPushed(app, event)
            global SpecDataPlot wavelengths ShadeArea OpenedFolder
            [~, name] = fileparts(OpenedFolder);
            OutputName = OpenedFolder + "\" + name + "_SPECTRADATA.txt";
            sorted_wavelengths = sort(str2double(wavelengths));
            DataToWrite = [sorted_wavelengths' SpecDataPlot];

            Headers = [];
            for i =1:1:size(SpecDataPlot,2)
                Headers(1,i+1) = mean(ShadeArea{i,1});
            end
            OutputData = [Headers; DataToWrite]';
            format_str = ['%.1f' repmat(' %.7f',1,size(SpecDataPlot,2)) '\r\n'];
            RGID = fopen(OutputName,'w');
            fprintf(RGID,format_str,OutputData);
            fclose(RGID);
        end

        % Button pushed function: LoadLogData
        function loadLogDataButtonPushed(app, event)
            clearvars -global
            global KinInd; KinInd = 1;
            global IsLogDataOnly plot_wavelengths T
            [FileName, FilePath] = uigetfile('*.csv');
            app.LoadCreateError.Text = "log data: " + FileName;
            cla(app.KinPlot, "reset");
            cla(app.SpecPlot,"reset");
            T = readtable(fullfile(FilePath, FileName));
            disp(T)
            wavelengths = T.Properties.VariableNames(2:end);
            for i = 1:1:length(wavelengths)
                wavelengths(i) = erase(wavelengths(i), 'x');
            end
            IsLogDataOnly = true;
            plot_wavelengths = sort(str2double(wavelengths));
            app.GridLayout3.Visible = true;
            app.GridLayout9.Visible = true;
            app.CenterPanel.Visible = true;
            app.RightPanel.Visible = true;
            app.SpecPlot.Visible = false;
            app.SpectraTimeWidthLabel.Visible = false;
            app.LinearDataButton.Visible = false;
            app.LogDataButton.Visible=false;
            app.SpectraTimeWidthSlider.Visible = false;
            app.ExportLogDataCSVButton.Visible = false;
            app.ExportSpectraDataButton.Visible = false;
            app.TimeFields.Visible = false;
            app.CentertimesforspectraSeperatewithcolonLabel.Visible = false;
            app.KeepSpecPlotYaxisfixedCheckBox.Visible = false;
            app.BackgroundLvlDisplay.Visible = false;

            plotLogData(app);


        end

        % Value changed function: TimeFields
        function TimeFieldsValueChanged(app, event)
            value = app.TimeFields.Value;
            RGBuildSpecData(app);
        end

        % Button pushed function: SaveVarstoWorkspaceButton
        function SaveVarstoWorkspaceButtonPushed(app, event)
            global DataRG BackgroundLvl SpecDataPlot %#ok<NUSED> 
            save_to_base(1);
        end

        % Button pushed function: LogLogFigureButton
        function LogLogFigureButtonPushed(app, event)
            global LinData LogData Time LogTimeArray KinInd plot_wavelengths
            
            % Disable button while second app is open
            app.LogLogFigureButton.Enable = 'off';
            
            % Open LogLog App with required arguments
            app.LogApp = LogLogApp(app, LinData, LogData, Time, LogTimeArray, KinInd, plot_wavelengths); 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete both apps when closing the figures
            delete(app.LogApp)
            delete(app)
        end

        % Button pushed function: ExportFiguresButton
        function ExportFiguresButtonPushed(app, event)
            global KinInd plot_wavelengths FolderPath
            
            % Create string for figure titles
            string = "@" + plot_wavelengths(KinInd);
            name1 = sprintf('KinFigure%s.pdf',string);
            name2 = sprintf('SpecFigure%s.png',string);
            
            % Create full path for each name
            place1 = fullfile(FolderPath,name1);
            place2 = fullfile(FolderPath,name2);
            
            % Export Vector Image for KinPlot and PNG for SpecPlot
            exportgraphics(app.KinPlot, place1, 'ContentType','vector');
            exportgraphics(app.SpecPlot, place2, 'Resolution', 300);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadTDMSdataButton = uibutton(app.GridLayout2, 'push');
            app.LoadTDMSdataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButton, true);
            app.LoadTDMSdataButton.Text = 'Load TDMS data';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.LinearDataButton = uibutton(app.GridLayout9, 'push');
            app.LinearDataButton.ButtonPushedFcn = createCallbackFcn(app, @setLinData, true);
            app.LinearDataButton.Text = 'Linear Data';
            app.LogDataButton = uibutton(app.GridLayout9, 'push');
            app.LogDataButton.ButtonPushedFcn = createCallbackFcn(app, @setLogData, true);
            app.LogDataButton.Text = 'Log Data';
            app.LoadCreateError = uilabel(app.GridLayout2);
            app.LoadCreateError.Text = '';
            app.LoadLogData = uibutton(app.GridLayout2, 'push');
            app.LoadLogData.ButtonPushedFcn = createCallbackFcn(app, @loadLogDataButtonPushed, true);
            app.LoadLogData.Text = 'Load Log Data';
            app.SaveVarstoWorkspaceButton = uibutton(app.GridLayout2, 'push');
            app.SaveVarstoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVarstoWorkspaceButtonPushed, true);
            app.SaveVarstoWorkspaceButton.Text = 'Save Vars to Workspace';
            app.LogLogFigureButton = uibutton(app.GridLayout2, 'push');
            app.LogLogFigureButton.ButtonPushedFcn = createCallbackFcn(app, @LogLogFigureButtonPushed, true);
            app.LogLogFigureButton.Text = 'LogLog Figure';
            app.ExportFiguresButton = uibutton(app.GridLayout2, 'push');
            app.ExportFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFiguresButtonPushed, true);
            app.ExportFiguresButton.Text = 'Export Figures';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.SpecPlot = uiaxes(app.GridLayout3);
            title(app.SpecPlot, 'Spectra')
            xlabel(app.SpecPlot, 'Wavelength (nm)')
            ylabel(app.SpecPlot, 'ΔOD or %Abs')
            app.KinPlot = uiaxes(app.GridLayout3);
            title(app.KinPlot, 'Kinetics')
            xlabel(app.KinPlot, 'Time (s)')
            ylabel(app.KinPlot, 'ΔOD or %Abs')
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.KeepKinPlotaxisfixedCheckBox = uicheckbox(app.GridLayout5);
            app.KeepKinPlotaxisfixedCheckBox.ValueChangedFcn = createCallbackFcn(app, @KeepKinPlotaxisfixed, true);
            app.KeepKinPlotaxisfixedCheckBox.Text = 'Keep Y axis fixed';
            app.ButtonGroup = uibuttongroup(app.GridLayout5);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectLogOrLin, true);
            app.LogYScaleButton = uiradiobutton(app.ButtonGroup);
            app.LogYScaleButton.Text = 'Log Y Scale';
            app.LinearYscaleButton = uiradiobutton(app.ButtonGroup);
            app.LinearYscaleButton.Text = 'Linear Y scale';
            app.LinearYscaleButton.Value = true;
            app.KeepSpecPlotYaxisfixedCheckBox = uicheckbox(app.GridLayout3);
            app.KeepSpecPlotYaxisfixedCheckBox.ValueChangedFcn = createCallbackFcn(app, @KeepSpecPlotYaxisfixed, true);
            app.KeepSpecPlotYaxisfixedCheckBox.Text = 'Keep Y axis fixed';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.PrevButton = uibutton(app.GridLayout6, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPressed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.GridLayout6, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPressed, true);
            app.NextButton.Text = 'Next';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.SpectraTimeWidthLabel = uilabel(app.GridLayout4);
            app.SpectraTimeWidthLabel.Text = 'Spectra Time Width(%)';
            app.SpectraTimeWidthSlider = uislider(app.GridLayout4);
            app.SpectraTimeWidthSlider.ValueChangedFcn = createCallbackFcn(app, @TimeWidthChangedFcn, true);
            app.SpectraTimeWidthSlider.ValueChangingFcn = createCallbackFcn(app, @TimeWidthChangingFcn, true);
            app.SpectraTimeWidthSlider.MinorTicks = [0 5 10 15 25 30 35 45 50 55 65 70 75 85 90 95];
            app.SpectraTimeWidthSlider.Value = 10;
            app.KineticLevelDisplay = uilabel(app.GridLayout4);
            app.KineticLevelDisplay.Text = 'Displaying kinetic at _ nm';
            app.CentertimesforspectraSeperatewithcolonLabel = uilabel(app.GridLayout4);
            app.CentertimesforspectraSeperatewithcolonLabel.Text = {'Center times for spectra'; '(Seperate with colon)'};
            app.TimeFields = uieditfield(app.GridLayout4, 'text');
            app.TimeFields.ValueChangedFcn = createCallbackFcn(app, @TimeFieldsValueChanged, true);
            app.TimeFields.Value = '1E-6; 1E-5; 1E-4; 1E-3; 1E-2; 1E-1';
            app.ExportLogDataCSVButton = uibutton(app.GridLayout4, 'push');
            app.ExportLogDataCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLogDataCSVButtonPushed, true);
            app.ExportLogDataCSVButton.Text = 'Export Log Data CSV';
            app.ExportSpectraDataButton = uibutton(app.GridLayout4, 'push');
            app.ExportSpectraDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSpectraDataButtonPushed, true);
            app.ExportSpectraDataButton.Text = 'Export SpectraData';
            app.LogarithmicdatawillbeexportedtoCSVLabel = uilabel(app.GridLayout4);
            app.LogarithmicdatawillbeexportedtoCSVLabel.Visible = 'off';
            app.LogarithmicdatawillbeexportedtoCSVLabel.Text = 'Logarithmic data will be exported to CSV';
            app.BackgroundLvlDisplay = uilabel(app.GridLayout4);
            app.BackgroundLvlDisplay.Text = 'Background level was _ V';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SolarSpecGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end